classdef DialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddnewsegmentUIFigure       matlab.ui.Figure
        LoadButton                  matlab.ui.control.Button
        PeakIndicesEditFieldLabel   matlab.ui.control.Label
        PeakIndicesEditField        matlab.ui.control.EditField
        OnsetIndicesEditFieldLabel  matlab.ui.control.Label
        OnsetIndicesEditField       matlab.ui.control.EditField
        PPGEditFieldLabel           matlab.ui.control.Label
        PPGEditField                matlab.ui.control.EditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField  matlab.ui.control.NumericEditField
        HzLabel                     matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        NameEditFieldLabel          matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        PleaseusetoseparatedifferentnumbersLabel  matlab.ui.control.Label
        LoadPPGfromcsvfileLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description        
    end
    
    properties (Access = public)
        NameArray; % Description
        Segment;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, nameArray)
            movegui(app.AddnewsegmentUIFigure,'center');
            app.CallingApp = mainApp;
            t = nameArray;
            app.NameArray = t;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            filter = {'*.csv'};
            [fileName, path,index] = uigetfile(filter,'save PPG','synthetic_ppg');
            if index ~= 0
                t = readmatrix(fullfile(path,fileName)); 
                assignin('base','test_t',t);
                text = mat2str(rmmissing(t(:)));
                app.PPGEditField.Value = text(2:end-1);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if length(app.NameEditField.Value) == 0
                msgbox('Please add a name.');
            else
                if ismember(app.NameEditField.Value,app.NameArray)
                    msgbox(['The name ''' app.NameEditField.Value ''' already exists, please use another name']);
                else                    
                    app.Segment.title = app.NameEditField.Value;
                    if app.SamplingFrequencyEditField.Value == 0
                        msgbox('Please modify the sampling frequency');
                    else
                        app.Segment.Fs = app.SamplingFrequencyEditField.Value;
                        
                        if length(app.PPGEditField.Value) < 5*app.Segment.Fs
                            msgbox('The PPG length should greater than 5 seconds. \n Please add more samples or modify the samlping frequency');
                        else
                            app.Segment.ppg = str2num(app.PPGEditField.Value);
                            if length(app.PeakIndicesEditField.Value) == 0
                                msgbox('Please add the peak indices');
                            else
                                app.Segment.Labels.Peak = str2num(app.PeakIndicesEditField.Value);
                                if length(app.OnsetIndicesEditField.Value) == 0
                                    msgbox('Please add the Onset indices');
                                else
                                    app.Segment.Labels.Onset = str2num(app.OnsetIndicesEditField.Value);
                                    updateNewSegment(app.CallingApp,app.Segment);
                                    app.CallingApp.NewButton.Enable = 'on';
                                    delete(app);
                                end
                            end
                        end
                    end
                end
            end
                
                
        end

        % Close request function: AddnewsegmentUIFigure
        function AddnewsegmentUIFigureCloseRequest(app, event)

            app.CallingApp.NewButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.NewButton.Enable = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddnewsegmentUIFigure = uifigure('Visible', 'off');
            app.AddnewsegmentUIFigure.Name = 'Add new segment';
            app.AddnewsegmentUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddnewsegmentUIFigureCloseRequest, true);
            app.LoadButton = uibutton(app.AddnewsegmentUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PeakIndicesEditFieldLabel = uilabel(app.AddnewsegmentUIFigure);
            app.PeakIndicesEditFieldLabel.Text = 'Peak Indices';
            app.PeakIndicesEditField = uieditfield(app.AddnewsegmentUIFigure, 'text');
            app.OnsetIndicesEditFieldLabel = uilabel(app.AddnewsegmentUIFigure);
            app.OnsetIndicesEditFieldLabel.Text = 'Onset Indices';
            app.OnsetIndicesEditField = uieditfield(app.AddnewsegmentUIFigure, 'text');
            app.PPGEditFieldLabel = uilabel(app.AddnewsegmentUIFigure);
            app.PPGEditFieldLabel.Text = 'PPG';
            app.PPGEditField = uieditfield(app.AddnewsegmentUIFigure, 'text');
            app.SamplingFrequencyEditFieldLabel = uilabel(app.AddnewsegmentUIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.AddnewsegmentUIFigure, 'numeric');
            app.SamplingFrequencyEditField.Limits = [0 Inf];
            app.HzLabel = uilabel(app.AddnewsegmentUIFigure);
            app.HzLabel.Text = 'Hz';
            app.SaveButton = uibutton(app.AddnewsegmentUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.AddnewsegmentUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NameEditFieldLabel = uilabel(app.AddnewsegmentUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.AddnewsegmentUIFigure, 'text');
            app.PleaseusetoseparatedifferentnumbersLabel = uilabel(app.AddnewsegmentUIFigure);
            app.PleaseusetoseparatedifferentnumbersLabel.Text = 'Please use ‘;’ to separate different numbers ';
            app.LoadPPGfromcsvfileLabel = uilabel(app.AddnewsegmentUIFigure);
            app.LoadPPGfromcsvfileLabel.Text = 'Load PPG from csv file';
            % Show the figure after all components are created
            app.AddnewsegmentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddnewsegmentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddnewsegmentUIFigure)
        end
    end
end